classdef HeliosV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PrevioslySolarisV2Label        matlab.ui.control.Label
        HeliosV1Label                  matlab.ui.control.Label
        EncederCargaSwitch             matlab.ui.control.ToggleSwitch
        EncederCargaSwitchLabel        matlab.ui.control.Label
        PotenciaDeEntradaGauge         matlab.ui.control.Gauge
        PotenciaDeEntradaGaugeLabel    matlab.ui.control.Label
        mWLabel                        matlab.ui.control.Label
        CorrienteDelSistemaGauge       matlab.ui.control.Gauge
        CorrienteDelSistemaGaugeLabel  matlab.ui.control.Label
        mALabel                        matlab.ui.control.Label
        VLabel                         matlab.ui.control.Label
        VoltajedeEntradaGauge          matlab.ui.control.Gauge
        VoltajedeEntradaGaugeLabel     matlab.ui.control.Label
        REDLamp                        matlab.ui.control.Lamp
        REDLampLabel                   matlab.ui.control.Label
        YELLOWLamp                     matlab.ui.control.Lamp
        YELLOWLampLabel                matlab.ui.control.Label
        GREENLamp                      matlab.ui.control.Lamp
        GREENLampLabel                 matlab.ui.control.Label
        BLUELamp_2                     matlab.ui.control.Lamp
        BLUELamp_2Label                matlab.ui.control.Label
        Carga4Button                   matlab.ui.control.Button
        Carga3Button                   matlab.ui.control.Button
        Carga2Button                   matlab.ui.control.Button
        Carga1Button                   matlab.ui.control.Button
        IVEntradaAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arduinoSerial

        % Datos para las curvas I-V
        datosVin
        datosVout
        datosI

         % Datos de adquisición por combinación
        datosPorCarga
        combinacionActual
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
        function conectarArduino(app)
            app.arduinoSerial = serialport("COM3", 9600);
        
            % El Arduino termina cada dato con salto de línea
            configureTerminator(app.arduinoSerial, "LF");
        
            % Ejecutar correctamente el método de la aplicación
            configureCallback(app.arduinoSerial, "terminator", ...
                @(src,event) recibirDatos(app,src,event));
        
            pause(2);
        
            % Comenzar siempre con Carga 1
            seleccionarCarga(app, 1);
        end
        function recibirDatos(app, src, event)

            % Leer una línea completa de Arduino
            linea = readline(src);
    
            % Separar los datos
            datos = split(linea, ",");
    
            % Comprobar que llegaron exactamente 2 datos
            if numel(datos) ~= 2
                return;
            end
    
            % Convertir texto a números
            Vin  = str2double(datos(1));
            I_mA = str2double(datos(2));
    
            % Comprobar que los datos sean válidos
            if any(isnan([Vin I_mA]))
                return;
            end

            % =========================================================
            % RECONSTRUIR LA TENSIÓN REAL DEL PANEL
            % =========================================================
            
            I_A = I_mA / 1000;
            
            Vpanel = Vin + 220 * I_A;
            
            % Potencia real del panel
            Pentrada_mW = Vpanel * I_mA;
            
            % Actualizar gauges
            app.VoltajedeEntradaGauge.Value = Vpanel;
            app.CorrienteDelSistemaGauge.Value = I_mA;
            app.PotenciaDeEntradaGauge.Value = Pentrada_mW;
    
            % Actualizar valores numéricos
            app.VLabel.Text = sprintf('%.2f [V]', Vpanel);
            app.mALabel.Text = sprintf('%.2f [mA]', I_mA);
            app.mWLabel.Text = sprintf('%.2f [mW]', Pentrada_mW);
            % =========================================================
            % ADQUISICIÓN
            % =========================================================
            
            % Si el Switch está apagado, NO guardar datos para la gráfica
            if ~strcmp(app.EncederCargaSwitch.Value, 'On')
                return;
            end
            
            % =========================================================
            % GUARDAR DATOS
            % =========================================================
            
            app.datosVin(end+1) = Vpanel;
            app.datosI(end+1) = I_mA;

            % =========================================================
            % GUARDAR DATOS DE LA COMBINACIÓN ACTUAL
            % =========================================================
        
            if app.combinacionActual >= 1 && app.combinacionActual <= 15
        
                app.datosPorCarga{app.combinacionActual}(end+1,:) = ...
                    [Vpanel I_mA];
        
            end

        end
        function seleccionarCarga(app, carga)
    
        % Apagar todos los indicadores
        app.BLUELamp_2.Color = [0.2 0.2 0.2];
        app.GREENLamp.Color = [0.2 0.2 0.2];
        app.YELLOWLamp.Color = [0.2 0.2 0.2];
        app.REDLamp.Color = [0.2 0.2 0.2];

        % Vector de cargas 
        vectorCargas = [0 0 0 0];
    
            % Encender solamente la carga seleccionada
            switch carga
                case 1
                    app.REDLamp.Color = [1 0 0];
                    vectorCargas = [1 0 0 0];
                case 2
                    app.YELLOWLamp.Color = [1 1 0];
                    vectorCargas = [0 1 0 0];
                case 3
                    app.GREENLamp.Color = [0 1 0];
                    vectorCargas = [0 0 1 0];
                case 4
                    app.BLUELamp_2.Color = [0 0 1];
                    vectorCargas = [0 0 0 1];
            end

        % Convertir el vector a una cadena de 4 caracteres
        orden = sprintf('%d%d%d%d', vectorCargas); 
        % Enviar la orden a Arduino 
        write(app.arduinoSerial, orden, "char");
    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        rutaSonido = fullfile( ...
            'C:\Users\Harnol Lopez\Desktop\arduinosolar\HeliosV1\AppMatLab\Sonidos', ...
            'Inicio.mp3');
        
        [y, Fs] = audioread(rutaSonido);
        sound(y, Fs);

        % Inicializar datos
        app.datosVin = [];
        app.datosVout = [];
        app.datosI = [];

        app.datosPorCarga = {};
        app.combinacionActual = 0;
    
        % Limpiar gráficas al iniciar
        cla(app.IVEntradaAxes);
    
        % Quitar títulos
        title(app.IVEntradaAxes, '');
        xlabel(app.IVEntradaAxes, '');
        ylabel(app.IVEntradaAxes, '');
    
        % Quitar cuadrícula
        grid(app.IVEntradaAxes, 'off');

        drawnow;
    
        % Conectar Arduino
        conectarArduino(app);

        end

        % Button pushed function: Carga1Button
        function Carga1ButtonPushed(app, event)
            rutaSonido = fullfile( ...
                'C:\Users\Harnol Lopez\Desktop\arduinosolar\HeliosV1\AppMatLab\Sonidos', ...
                'luz_rojo.wav');
            
            [y, Fs] = audioread(rutaSonido);
            sound(y, Fs);
            seleccionarCarga(app, 1);
        end

        % Button pushed function: Carga2Button
        function Carga2ButtonPushed(app, event)
            rutaSonido = fullfile( ...
                'C:\Users\Harnol Lopez\Desktop\arduinosolar\HeliosV1\AppMatLab\Sonidos', ...
                'luz_amarillo.wav');
            
            [y, Fs] = audioread(rutaSonido);
            sound(y, Fs);
            seleccionarCarga(app, 2);
        end

        % Button pushed function: Carga3Button
        function Carga3ButtonPushed(app, event)
            rutaSonido = fullfile( ...
                'C:\Users\Harnol Lopez\Desktop\arduinosolar\HeliosV1\AppMatLab\Sonidos', ...
                'luz_verde.wav');
            
            [y, Fs] = audioread(rutaSonido);
            sound(y, Fs);
            seleccionarCarga(app, 3);
        end

        % Button pushed function: Carga4Button
        function Carga4ButtonPushed(app, event)
            rutaSonido = fullfile( ...
                'C:\Users\Harnol Lopez\Desktop\arduinosolar\HeliosV1\AppMatLab\Sonidos', ...
                'luz_azul.wav');
            
            [y, Fs] = audioread(rutaSonido);
            sound(y, Fs);
            seleccionarCarga(app, 4);
        end

        % Value changed function: EncederCargaSwitch
        function EncederCargaSwitchValueChanged(app, event)
            value = app.EncederCargaSwitch.Value;
                    
            if strcmp(value, 'Off')
            
                % =====================================================
                % APAGAR TODAS LAS CARGAS
                % =====================================================
            
                orden = '0000';
            
                write(app.arduinoSerial, orden, "char");
            
                % Reiniciar adquisición
                app.datosVin = [];
                app.datosI = [];
                app.datosPorCarga = {};
                app.combinacionActual = 0;
            
                % =====================================================
                % LIMPIAR GRÁFICA
                % =====================================================
            
                cla(app.IVEntradaAxes);
            
                title(app.IVEntradaAxes, '');
                xlabel(app.IVEntradaAxes, '');
                ylabel(app.IVEntradaAxes, '');
            
                grid(app.IVEntradaAxes, 'off');
            
                drawnow;
            
            elseif strcmp(value, 'On')
            
                % =====================================================
                % NUEVA ADQUISICIÓN
                % =====================================================
            
                app.datosVin = [];
                app.datosI = [];
            
                % Crear espacio para las 15 combinaciones
                app.datosPorCarga = cell(1,15);
            
                app.combinacionActual = 0;
            
                % =====================================================
                % PARÁMETROS DEL BARRIDO
                % =====================================================
            
                tiempoTotal = 120;       % segundos
                numeroCombinaciones = 15;
            
                tiempoPorCombinacion = ...
                    tiempoTotal / numeroCombinaciones;
            
                % =====================================================
                % BARRIDO DE LAS 15 COMBINACIONES
                % =====================================================
            
                for numero = 1:numeroCombinaciones
            
                    % Indicar qué combinación se está midiendo
                    app.combinacionActual = numero;
            
                    % Convertir número a código binario de 4 bits
                    orden = dec2bin(numero, 4);
            
                    % Enviar combinación a Arduino
                    write(app.arduinoSerial, orden, "char");
            
                    % Mantener esta combinación durante 8 segundos
                    pause(tiempoPorCombinacion);
            
                end
            
                % =====================================================
                % TERMINÓ EL BARRIDO
                % =====================================================
            
                app.combinacionActual = 0;
            
                % Apagar todas las cargas
                write(app.arduinoSerial, '0000', "char");
            
                % =====================================================
                % OBTENER UN PUNTO REPRESENTATIVO POR COMBINACIÓN
                % =====================================================
            
                Vprom = zeros(1,15);
                Iprom = zeros(1,15);
            
                for k = 1:15
            
                    datos = app.datosPorCarga{k};
            
                    if ~isempty(datos)
            
                        Vprom(k) = mean(datos(:,1));
                        Iprom(k) = mean(datos(:,2));
            
                    else
            
                        Vprom(k) = NaN;
                        Iprom(k) = NaN;
            
                    end
            
                end
            
                % =====================================================
                % ELIMINAR COMBINACIONES SIN DATOS
                % =====================================================
            
                datosValidos = ~isnan(Vprom) & ~isnan(Iprom);
            
                Vprom = Vprom(datosValidos);
                Iprom = Iprom(datosValidos);
            
                % =====================================================
                % ORDENAR LOS PUNTOS SEGÚN LA TENSIÓN
                % =====================================================
            
                [VOrdenado, indice] = sort(Vprom);
            
                IOrdenada = Iprom(indice);
            
                % =====================================================
                % GUARDAR LA CURVA EXPERIMENTAL
                % =====================================================
            
                app.datosVin = VOrdenado;
                app.datosI = IOrdenada;
            
                % =====================================================
                % CURVA IDEALIZADA PARA VISUALIZACIÓN
                % =====================================================
            
                Vsuave = linspace( ...
                    min(VOrdenado), ...
                    max(VOrdenado), ...
                    300);
            
                Isuave = interp1( ...
                    VOrdenado, ...
                    IOrdenada, ...
                    Vsuave, ...
                    'pchip');
            
                % =====================================================
                % GRAFICAR AMBAS CURVAS
                % =====================================================
            
                cla(app.IVEntradaAxes);
            
                % -----------------------------------------------------
                % CURVA EXPERIMENTAL
                % -----------------------------------------------------
            
                plot(app.IVEntradaAxes, ...
                    VOrdenado, IOrdenada, '-o', ...
                    'LineWidth', 1.5, ...
                    'MarkerSize', 5);
            
                hold(app.IVEntradaAxes, 'on');
            
                % -----------------------------------------------------
                % CURVA IDEALIZADA
                % -----------------------------------------------------
            
                plot(app.IVEntradaAxes, ...
                    Vsuave, Isuave, '--', ...
                    'LineWidth', 2);
            
                hold(app.IVEntradaAxes, 'off');
            
                % =====================================================
                % CONFIGURACIÓN DE LA GRÁFICA
                % =====================================================
            
                title(app.IVEntradaAxes, ...
                    'Curva I-V de Entrada');
            
                xlabel(app.IVEntradaAxes, ...
                    'Voltaje del Panel [V]');
            
                ylabel(app.IVEntradaAxes, ...
                    'Corriente [mA]');
            
                legend(app.IVEntradaAxes, ...
                    'Datos experimentales', ...
                    'Curva idealizada', ...
                    'Location', 'best');
            
                grid(app.IVEntradaAxes, 'on');
            
                drawnow;
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IVEntradaAxes = uiaxes(app.UIFigure);
            title(app.IVEntradaAxes, 'Curva I-V de Entrada')
            xlabel(app.IVEntradaAxes, 'Voltaje de Entrada [V]')
            ylabel(app.IVEntradaAxes, 'Corriente [mA]')
            zlabel(app.IVEntradaAxes, 'Z')
            app.Carga1Button = uibutton(app.UIFigure, 'push');
            app.Carga1Button.ButtonPushedFcn = createCallbackFcn(app, @Carga1ButtonPushed, true);
            app.Carga1Button.Text = 'Carga 1';
            app.Carga2Button = uibutton(app.UIFigure, 'push');
            app.Carga2Button.ButtonPushedFcn = createCallbackFcn(app, @Carga2ButtonPushed, true);
            app.Carga2Button.Text = 'Carga 2';
            app.Carga3Button = uibutton(app.UIFigure, 'push');
            app.Carga3Button.ButtonPushedFcn = createCallbackFcn(app, @Carga3ButtonPushed, true);
            app.Carga3Button.Text = 'Carga 3';
            app.Carga4Button = uibutton(app.UIFigure, 'push');
            app.Carga4Button.ButtonPushedFcn = createCallbackFcn(app, @Carga4ButtonPushed, true);
            app.Carga4Button.Text = 'Carga 4';
            app.BLUELamp_2Label = uilabel(app.UIFigure);
            app.BLUELamp_2Label.Text = 'BLUE';
            app.BLUELamp_2 = uilamp(app.UIFigure);
            app.GREENLampLabel = uilabel(app.UIFigure);
            app.GREENLampLabel.Text = 'GREEN';
            app.GREENLamp = uilamp(app.UIFigure);
            app.YELLOWLampLabel = uilabel(app.UIFigure);
            app.YELLOWLampLabel.Text = 'YELLOW';
            app.YELLOWLamp = uilamp(app.UIFigure);
            app.REDLampLabel = uilabel(app.UIFigure);
            app.REDLampLabel.Text = 'RED';
            app.REDLamp = uilamp(app.UIFigure);
            app.VoltajedeEntradaGaugeLabel = uilabel(app.UIFigure);
            app.VoltajedeEntradaGaugeLabel.Text = 'Voltaje de Entrada';
            app.VoltajedeEntradaGauge = uigauge(app.UIFigure, 'circular');
            app.VoltajedeEntradaGauge.Limits = [0 4];
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = '0.00 [V]';
            app.mALabel = uilabel(app.UIFigure);
            app.mALabel.Text = '0.00 [mA]';
            app.CorrienteDelSistemaGaugeLabel = uilabel(app.UIFigure);
            app.CorrienteDelSistemaGaugeLabel.Text = 'Corriente Del Sistema';
            app.CorrienteDelSistemaGauge = uigauge(app.UIFigure, 'circular');
            app.CorrienteDelSistemaGauge.Limits = [0 1.5];
            app.mWLabel = uilabel(app.UIFigure);
            app.mWLabel.Text = '0.00 [mW]';
            app.PotenciaDeEntradaGaugeLabel = uilabel(app.UIFigure);
            app.PotenciaDeEntradaGaugeLabel.Text = 'Potencia De Entrada';
            app.PotenciaDeEntradaGauge = uigauge(app.UIFigure, 'circular');
            app.PotenciaDeEntradaGauge.Limits = [0 2];
            app.EncederCargaSwitchLabel = uilabel(app.UIFigure);
            app.EncederCargaSwitchLabel.Text = 'Enceder Carga';
            app.EncederCargaSwitch = uiswitch(app.UIFigure, 'toggle');
            app.EncederCargaSwitch.ValueChangedFcn = createCallbackFcn(app, @EncederCargaSwitchValueChanged, true);
            app.HeliosV1Label = uilabel(app.UIFigure);
            app.HeliosV1Label.Text = 'Helios V1';
            app.PrevioslySolarisV2Label = uilabel(app.UIFigure);
            app.PrevioslySolarisV2Label.Text = 'Previosly Solaris V2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HeliosV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end